classdef Projectile_Motion_TP061159_Su_Xin_Hong < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SimulationTab                  matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        InitialDisplacementmEditFieldLabel  matlab.ui.control.Label
        InitialDisplacementmEditField  matlab.ui.control.NumericEditField
        MaxHeightmEditFieldLabel       matlab.ui.control.Label
        MaxHeightmEditField            matlab.ui.control.NumericEditField
        FlightTimesEditFieldLabel      matlab.ui.control.Label
        FlightTimesEditField           matlab.ui.control.NumericEditField
        HorizontalDisplacementmEditFieldLabel  matlab.ui.control.Label
        HorizontalDisplacementmEditField  matlab.ui.control.NumericEditField
        LocationDropDownLabel          matlab.ui.control.Label
        LocationDropDown               matlab.ui.control.DropDown
        InitialAngledegreeEditFieldLabel  matlab.ui.control.Label
        InitialAngledegreeEditField    matlab.ui.control.NumericEditField
        GravitationalAccGms2Label      matlab.ui.control.Label
        GravitationalAccGms2EditField  matlab.ui.control.NumericEditField
        InitialHeightmEditFieldLabel   matlab.ui.control.Label
        InitialHeightmEditField        matlab.ui.control.NumericEditField
        InitialVelocitymsEditFieldLabel  matlab.ui.control.Label
        InitialVelocitymsEditField     matlab.ui.control.NumericEditField
        PROJECTILEMOTIONSIMULATIONLabel  matlab.ui.control.Label
        GOButton                       matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        MiniGameTab                    matlab.ui.container.Tab
        STOPQUITButton                 matlab.ui.control.Button
        PointEditField                 matlab.ui.control.NumericEditField
        PointEditFieldLabel            matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        INITIALVELOCITYEditField       matlab.ui.control.NumericEditField
        INITIALVELOCITYLabel           matlab.ui.control.Label
        PROJECTILEMOTIONMINIGAMELabel  matlab.ui.control.Label
        STARTRESETButton               matlab.ui.control.Button
        CREATETARGETButton             matlab.ui.control.Button
        FIREANGLEEditField             matlab.ui.control.NumericEditField
        FIREANGLEEditFieldLabel        matlab.ui.control.Label
        FIREButton                     matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %To be used across the whole program
        target
        target2
    end
    
    methods (Access = public)
        
        function reset(app)
            %To be called by all button callback functions
            app.FIREANGLEEditField.Value = 0;
            app.INITIALVELOCITYEditField.Value = 0;
            app.PointEditField.Value = 0;
            cla(app.UIAxes2)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            %Assign the correct gravitational acceleration to g according
            %to drop down menu
            if strcmp('Earth', app.LocationDropDown.Value)
                g = 9.81;
            elseif strcmp('Moon',app.LocationDropDown.Value)
                g = 1.62;
            elseif strcmp('Sun',app.LocationDropDown.Value)
                g = 274;
            elseif strcmp('Mars',app.LocationDropDown.Value)
                g = 3.72;
            end
            %Display the gravitational acceleration of the planet chosen
            app.GravitationalAccGms2EditField.Value = g;
            %Accepting inputs from user
            v0 = app.InitialVelocitymsEditField.Value;
            y0 = app.InitialHeightmEditField.Value;
            x0 = app.InitialDisplacementmEditField.Value;
            theta = app.InitialAngledegreeEditField.Value;
            %Ensure no angle over 90 degree
            if theta > 90
                theta = 90;
            elseif theta < 0
                theta = 0;
            end
        
            %Assigning the inputs into a variable to make the calculation
            %looks less complex
            b = v0*sind(theta);
            a = -g/2;
            c = y0;        
            %Calculation For Flight Time
            t_flight = (-b-sqrt(b^2-4*a*c))/(2*a);
            %Display Flight Time
            app.FlightTimesEditField.Value = t_flight;
            %Calculation For Horizontal Displacement
            h_disp = v0*cosd(theta)*t_flight + x0;
            %Display Horizontal Displacement
            app.HorizontalDisplacementmEditField.Value = h_disp;
            %Component's Velocity
            t = linspace(0,t_flight,30); %Create 30 points to be plotted
            vx0 = v0*cosd(theta);
            vy0 = v0*sind(theta);
        
            %Calculation For Maximum Height
            m_height = y0+(v0.^2)*(sind(theta))^2/(2*g);
            app.MaxHeightmEditField.Value = m_height;
        
            x = vx0.*t+x0;
            y = -(g/2).*t.^2+vy0*t+y0;
            
            %Recording in Table
            tablerecord = app.UITable.Data;
            [n,m] = size(tablerecord);
            tablerecord(n+1, 1:8) = [v0 y0 x0 theta h_disp t_flight m_height g];
            app.UITable.Data = tablerecord;
            
            %Plotting the Graph
            hold (app.UIAxes, "on")
            rectangle(app.UIAxes, 'Position', [0 0 9999 0], 'EdgeColor', 'b')
            if m_height >= h_disp
                axis(app.UIAxes, [x0, (m_height*1.05), 0, (m_height*1.05)])
            else
                axis(app.UIAxes, [x0, (h_disp*1.05), 0, (h_disp*1.05)])
            end
            comet(app.UIAxes,x,y)
            legend(app.UIAxes,'Projection')
        end

        % Button pushed function: FIREButton
        function FIREButtonPushed(app, event)
            %Calling global variables to be used
            global target
            global target2
            %Let the user know that the program is calculating
            app.EditField.Value = "BIU~ ~ ~ ~ ~";
            %Accepting and assigning firing parameters
            g = 9.81;
            angle = app.FIREANGLEEditField.Value;
            v0 = app.INITIALVELOCITYEditField.Value;
            x0 = 0;
            y0 = 1;
            %Ensure no angle over 90 degree
            if angle > 90
                angle = 90;
            elseif angle < 0
                angle = 0;
            end
            %Reduce complexity
            b = v0*sind(angle);
            a = -g/2;
            c = y0;
            %Calculations to obtain the x displacement
            t_flight = (-b-sqrt(b^2-4*a*c))/(2*a);
            xdisp = v0*cosd(angle)*t_flight;
            t = linspace(0,t_flight,30); %Create 30 points to be plotted
            vx0 = v0*cosd(angle);
            vy0 = v0*sind(angle);
            x = vx0*t+x0;
            y = -(g/2)*t.^2+vy0*t+y0;
            hold (app.UIAxes2 ,"on");
            comet(app.UIAxes2, x, y)

            %Check if hit target or not
            upper = target(1);
            lower = target2;
            if (xdisp >= lower && xdisp <= upper)
                app.EditField.Value = "CONGRATULATIONS ! ! !";
                %Adding point for user
                app.PointEditField.Value = app.PointEditField.Value + 1;
                pause(3)
                CREATETARGETButtonPushed(app, event)
            else
                app.EditField.Value = "YOU MISSED ! ! !";
            end
        end

        % Button pushed function: CREATETARGETButton
        function CREATETARGETButtonPushed(app, event)
            cla(app.UIAxes2)
            %Drawing line for user to understand the game easier
            rectangle(app.UIAxes2, 'Position', [0 1 0.25 0], 'EdgeColor', 'm', 'LineWidth', 3)
            rectangle(app.UIAxes2, 'Position', [0 0 10 0], 'EdgeColor', 'r')
            axis(app.UIAxes2, [0, 10, -1, 5]);
            %Drawing ball for user to understand the game easier
            rectangle(app.UIAxes2, 'Position', [0 1 0.2 0.23], 'Curvature', [1 1], 'FaceColor', 'k', 'EdgeColor', 'k', 'LineWidth', 1);
            axis(app.UIAxes2, [0, 10, -1, 5]);
            %Starting Point is constant (0 ,1)
            %Create Random Target
            lower = 1;
            upper = 10;
            global target
            global target2
            target = [0, 0];
            target(1) = (upper-lower).*rand(1) + lower;
            target(1) = round(target(1));
            target2 = target(1) - 1;
            rectangle(app.UIAxes2, 'Position', [target2 0 1 0], 'EdgeColor', 'b', 'LineWidth', 3);
            app.EditField.Value = "Give inputs to hit target";
            pause(3)
            app.EditField.Value = "Then press FIRE button";
        end

        % Button pushed function: STARTRESETButton
        function STARTRESETButtonPushed(app, event)
            %To provide instructions reset the parameters, points and target
            reset(app)
            app.EditField.Value = 'Press CREATE TARGET';
        end

        % Button pushed function: STOPQUITButton
        function STOPQUITButtonPushed(app, event)
            %To end the game and wish goodbye to user
            reset(app);
            app.EditField.Value = 'BYE~ ~ ~ ~ ~';
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.InitialVelocitymsEditField.Value = 0;
            app.InitialHeightmEditField.Value = 0;
            app.InitialDisplacementmEditField.Value = 0;
            app.InitialAngledegreeEditField.Value = 0;
            app.HorizontalDisplacementmEditField.Value = 0;
            app.MaxHeightmEditField.Value = 0;
            app.FlightTimesEditField.Value = 0;
            app.UITable.Data = [];
            cla(app.UIAxes)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.UIAxes = uiaxes(app.SimulationTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ResetButton = uibutton(app.SimulationTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GOButton = uibutton(app.SimulationTab, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO ! ! !';
            app.PROJECTILEMOTIONSIMULATIONLabel = uilabel(app.SimulationTab);
            app.PROJECTILEMOTIONSIMULATIONLabel.Text = 'PROJECTILE MOTION SIMULATION';
            app.InitialVelocitymsEditField = uieditfield(app.SimulationTab, 'numeric');
            app.InitialVelocitymsEditFieldLabel = uilabel(app.SimulationTab);
            app.InitialVelocitymsEditFieldLabel.Text = 'Initial Velocity (m/s)';
            app.InitialHeightmEditField = uieditfield(app.SimulationTab, 'numeric');
            app.InitialHeightmEditFieldLabel = uilabel(app.SimulationTab);
            app.InitialHeightmEditFieldLabel.Text = 'Initial Height (m)';
            app.GravitationalAccGms2EditField = uieditfield(app.SimulationTab, 'numeric');
            app.GravitationalAccGms2Label = uilabel(app.SimulationTab);
            app.GravitationalAccGms2Label.Text = {'Gravitational Acc. G'; '(m/s^2)'};
            app.InitialAngledegreeEditField = uieditfield(app.SimulationTab, 'numeric');
            app.InitialAngledegreeEditFieldLabel = uilabel(app.SimulationTab);
            app.InitialAngledegreeEditFieldLabel.Text = 'Initial Angle (degree)';
            app.LocationDropDown = uidropdown(app.SimulationTab);
            app.LocationDropDown.Items = {'Earth', 'Moon', 'Sun', 'Mars'};
            app.LocationDropDown.Value = 'Earth';
            app.LocationDropDownLabel = uilabel(app.SimulationTab);
            app.LocationDropDownLabel.Text = 'Location';
            app.HorizontalDisplacementmEditField = uieditfield(app.SimulationTab, 'numeric');
            app.HorizontalDisplacementmEditFieldLabel = uilabel(app.SimulationTab);
            app.HorizontalDisplacementmEditFieldLabel.Text = 'Horizontal Displacement (m)';
            app.FlightTimesEditField = uieditfield(app.SimulationTab, 'numeric');
            app.FlightTimesEditFieldLabel = uilabel(app.SimulationTab);
            app.FlightTimesEditFieldLabel.Text = 'Flight Time (s)';
            app.MaxHeightmEditField = uieditfield(app.SimulationTab, 'numeric');
            app.MaxHeightmEditFieldLabel = uilabel(app.SimulationTab);
            app.MaxHeightmEditFieldLabel.Text = 'Max Height (m)';
            app.InitialDisplacementmEditField = uieditfield(app.SimulationTab, 'numeric');
            app.InitialDisplacementmEditFieldLabel = uilabel(app.SimulationTab);
            app.InitialDisplacementmEditFieldLabel.Text = 'Initial Displacement (m)';
            app.UITable = uitable(app.SimulationTab);
            app.UITable.ColumnName = {'Initial Velocity'; 'Initial Height'; 'Initial Displacement'; 'Initial Angle'; 'Horizontal Displacement'; 'Flight Time'; 'Max Height'; 'Gravitational Acc'};
            app.UITable.RowName = {};
            app.MiniGameTab = uitab(app.TabGroup);
            app.MiniGameTab.Title = 'Mini Game';
            app.UIAxes2 = uiaxes(app.MiniGameTab);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.FIREButton = uibutton(app.MiniGameTab, 'push');
            app.FIREButton.ButtonPushedFcn = createCallbackFcn(app, @FIREButtonPushed, true);
            app.FIREButton.Text = 'FIRE ! ! !';
            app.FIREANGLEEditFieldLabel = uilabel(app.MiniGameTab);
            app.FIREANGLEEditFieldLabel.Text = 'FIRE ANGLE';
            app.FIREANGLEEditField = uieditfield(app.MiniGameTab, 'numeric');
            app.CREATETARGETButton = uibutton(app.MiniGameTab, 'push');
            app.CREATETARGETButton.ButtonPushedFcn = createCallbackFcn(app, @CREATETARGETButtonPushed, true);
            app.CREATETARGETButton.Text = 'CREATE TARGET';
            app.STARTRESETButton = uibutton(app.MiniGameTab, 'push');
            app.STARTRESETButton.ButtonPushedFcn = createCallbackFcn(app, @STARTRESETButtonPushed, true);
            app.STARTRESETButton.Text = 'START / RESET';
            app.PROJECTILEMOTIONMINIGAMELabel = uilabel(app.MiniGameTab);
            app.PROJECTILEMOTIONMINIGAMELabel.Text = 'PROJECTILE MOTION MINI GAME';
            app.INITIALVELOCITYLabel = uilabel(app.MiniGameTab);
            app.INITIALVELOCITYLabel.Text = {'INITIAL'; 'VELOCITY'};
            app.INITIALVELOCITYEditField = uieditfield(app.MiniGameTab, 'numeric');
            app.EditField = uieditfield(app.MiniGameTab, 'text');
            app.PointEditFieldLabel = uilabel(app.MiniGameTab);
            app.PointEditFieldLabel.Text = 'Point';
            app.PointEditField = uieditfield(app.MiniGameTab, 'numeric');
            app.STOPQUITButton = uibutton(app.MiniGameTab, 'push');
            app.STOPQUITButton.ButtonPushedFcn = createCallbackFcn(app, @STOPQUITButtonPushed, true);
            app.STOPQUITButton.Text = 'STOP / QUIT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Projectile_Motion_TP061159_Su_Xin_Hong

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end